classdef POM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EyeSegmentationAplication       matlab.ui.Figure
        OptionsPanel                    matlab.ui.container.Panel
        FindsymmetryPanel               matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        FindsymmetryButton              matlab.ui.control.Button
        SegmentationPanel               matlab.ui.container.Panel
        TextArea_2                      matlab.ui.control.TextArea
        ChoosestartingpointButton       matlab.ui.control.Button
        AutomaticlyadjuststructuringelementCheckBox  matlab.ui.control.CheckBox
        SegmentationthresholdEditFieldLabel  matlab.ui.control.Label
        SegmentationthresholdEditField  matlab.ui.control.NumericEditField
        EndingthresholdEditFieldLabel   matlab.ui.control.Label
        EndingthresholdEditField        matlab.ui.control.NumericEditField
        StartsegmentationButton         matlab.ui.control.Button
        MakecorrectionsButton           matlab.ui.control.Button
        DvisualizationandregionpropertiesPanel  matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        Make3DvisualizationButton       matlab.ui.control.Button
        AreaEditFieldLabel              matlab.ui.control.Label
        AreaEditField                   matlab.ui.control.NumericEditField
        VolumeEditFieldLabel            matlab.ui.control.Label
        VolumeEditField                 matlab.ui.control.NumericEditField
        VolumeEditField_2Label          matlab.ui.control.Label
        VolumeEditField_2               matlab.ui.control.NumericEditField
        AreaEditField_2Label            matlab.ui.control.Label
        AreaEditField_2                 matlab.ui.control.NumericEditField
        LefteyeLabel                    matlab.ui.control.Label
        RighteyeLabel                   matlab.ui.control.Label
        SolidityEditFieldLabel          matlab.ui.control.Label
        SolidityEditField               matlab.ui.control.NumericEditField
        SolidityEditField_2Label        matlab.ui.control.Label
        SolidityEditField_2             matlab.ui.control.NumericEditField
        ImagesPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        NextButton                      matlab.ui.control.Button
        PreviousButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PathPanel                       matlab.ui.container.Panel
        LoadimagesButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        myFolder % Description
        currentFileNumber
        numberOfFiles
        FullImages                       %contains all images
        CroppedImages
        positionX
        positionY
        mask
        
        segmentationThreshold = 0.054;
        endingThreshold = 0.054;
        automaticlyAdjustStructuringElement = 1;
        
        symmetry;   %column that indicate symmetry of a head
        
        maskCorrectionApp
    end
    
    methods (Access = public)
        
        function  updateMask(app, mask)
            app.mask = mask;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
app.myFolder = uigetdir('D:\studia\VI sem\Moje\POM\Projekt-POM\MR-głowa\STU0001');%('C:\', 'Choose directory');                                              %obsługa wyjątku kiedy nic nie wybierzemy
% Check to make sure that folder actually exists.  Warn user if it doesn't.
if ~isfolder(app.myFolder)
  errorMessage = sprintf('Error: The following folder does not exist:\n%s', app.myFolder);
  uiwait(warndlg(errorMessage));
  return;
end
% Get a list of all files in the folder with the desired file name pattern.
filePattern = fullfile(app.myFolder, '*.dcm'); 

theFiles = dir(filePattern);

hWaitBar = waitbar(0,'Reading DICOM files');
for i=length(theFiles):-1:1
  file = fullfile(app.myFolder, theFiles(i).name);
%   app.D(:,:,i) = im2double(dicomread(file));                                                     %wczesniej było uint16
    %shrink region of interest
    I = double(dicomread(file));
    imSize = size(I,2);
    IROI = imcrop(I, [1 1 imSize imSize/3]);
    %Inorm = IROI.^2;
    app.FullImages(:,:,i) = I; 
    app.CroppedImages(:,:,i) = IROI;
%app.D(:,:,i) = double(dicomread(file));
    waitbar((length(theFiles)-i)/length(theFiles))
end
delete(hWaitBar)
    I1 = app.FullImages(:,:,1);
   imshow(I1,[],'Parent', app.UIAxes);
   drawnow; % Force display to update immediately.
  
  app.numberOfFiles = length(theFiles);
  app.currentFileNumber = 1;
  app.Label_2.Text = app.myFolder;
  
  formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,length(theFiles));
  app.Label.Visible = 'on';
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            %Show previous image  
            app.currentFileNumber = app.currentFileNumber-1;
            if(app.currentFileNumber == 0)          
            imshow(app.FullImages(:,:,app.numberOfFiles),[],'Parent', app.UIAxes);            
            app.currentFileNumber = app.numberOfFiles; 
            else             
            imshow(app.FullImages(:,:, app.currentFileNumber),[],'Parent', app.UIAxes);
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.currentFileNumber = app.currentFileNumber+1;
            if(app.currentFileNumber == app.numberOfFiles+1)
            imshow(app.FullImages(:,:,1),[],'Parent', app.UIAxes);            
            app.currentFileNumber = 1;             
            else 
            imshow(app.FullImages(:,:,app.currentFileNumber),[],'Parent', app.UIAxes);            
            end
            
    formatSpec = '%d/%d';
  app.Label.Text = sprintf(formatSpec,app.currentFileNumber,app.numberOfFiles);
  
  drawnow;
        end

        % Button pushed function: ChoosestartingpointButton
        function ChoosestartingpointButtonPushed(app, event)
            f = figure;
            imshow(app.CroppedImages(:,:,app.currentFileNumber),[])
            
            [x,y]=ginput(1);
            hold on
            plot(x,y, 'xg', 'MarkerSize', 10);
            app.positionX = round(x);
            app.positionY = round(y);
            
            pause(2);
            close(f)
           
        end

        % Button pushed function: StartsegmentationButton
        function StartsegmentationButtonPushed(app, event)
            %app.mask(:,:,:) = NaN; %previous mask reset???????????????
            
            previousMeanIntensity = 0;
            if app.automaticlyAdjustStructuringElement == 0
            se = strel('square',5); %element morfologiczny
            end
            
            currentFileNo = app.currentFileNumber;
            for i = 1:1:app.numberOfFiles
                I = app.CroppedImages(:,:, currentFileNo);
                
                if i == 1
                    x1 = app.positionX;
                    y1 = app.positionY;
                    y2 = y1;
                    
                    %position of second eye
                    sym = app.symmetry;
                    distance = abs(sym-app.positionX);
                    
                    if x1 > sym
                        x2 = sym - distance;
                    elseif x1 < sym
                        x2 = sym + distance;
                    else
                        warndlg('Point selected incorrectly');
                    end
                    
                end
                
                %normalization
                maxIntensity = max(max(I));
                I = I./maxIntensity;
                %power
                %I = I.^2;
                %square
%                 I = I.^(1/2);
                
                
                Isegmented1 = regiongrowing(I, y1, x1, app.segmentationThreshold); %segmentation, x and y are switched! mistake in function
                %second eye
                Isegmented2 = regiongrowing(I, y2, x2, app.segmentationThreshold);
                Isegmented = Isegmented1 + Isegmented2; %completed mask
                
                Isegmented = imbinarize(Isegmented);
                if app.automaticlyAdjustStructuringElement ==1
                    area = regionprops(Isegmented, 'area');
                    maskArea = area.Area;
                    %maskAreaMatrix(i,1) = maskArea;
                    if maskArea < 500 || maskArea > 0
                        se = strel('square', 5);
                    elseif maskArea > 500 || maskArea < 1000
                        se = strel('square', 7);
                    else
                        se = strel('square', 9);
                    end
                end
                
                Iclosed = imclose(Isegmented, se);                              %morfological close
                Iopen = imopen(Iclosed, se);                                    %morfological open
                %             Isegmented2 = imfill(Isegmented,'holes');
               
                %app.mask(:,:,i) = Iopen;
                maskTemp(:,:,i) = Iopen;
                centroid = regionprops(Iopen, 'Centroid');
                %             app.mask(:,:,i) = Isegmented2;
                %             centroid = regionprops(Isegmented2, 'Centroid');
                % new starting points coordinates (centroids)
                x1 = round(centroid(1).Centroid(1));
                y1 = round(centroid(1).Centroid(2));
                
                x2 = round(centroid(2).Centroid(1));
                y2 = round(centroid(2).Centroid(2));
                
                %mean intensity in the eye area
                [row, col] = find(Iopen);
                intensity = zeros(size(row,1), 1);
                for j = 1:size(row)
                    intensity(j) = I(row(j), col(j));
                end
                meanIntensity = mean(intensity);
                
                if abs(meanIntensity -previousMeanIntensity) < app.endingThreshold || i ==1
                    previousMeanIntensity = meanIntensity;
                    currentFileNo = currentFileNo +1;
%                     figure(1)
%                     imshow(Iopen);
%                     hold on
%                     plot(round(centroid(1).Centroid(1)), round(centroid(1).Centroid(2)), 'r*', "MarkerSize", 6);
%                     pause();
                elseif abs(meanIntensity -previousMeanIntensity) > app.endingThreshold || i == app.numberOfFiles
                    
                    break
                end
                
            end
%             masktemp = app.mask;
%             save('mask.mat', 'masktemp', '-mat');
            app.mask = maskTemp;
            montage(app.mask);
        end

        % Button pushed function: Make3DvisualizationButton
        function Make3DvisualizationButtonPushed(app, event)
         
figure;
p = patch(isosurface(double(app.mask)));
p.FaceColor = 'red';
p.EdgeColor = 'none';
daspect([1,1,1]);
camlight; 
lighting phong   
view(3)

stats = regionprops3(app.mask,'all')
app.AreaEditField.Value = round(stats.SurfaceArea(1),2);
app.AreaEditField_2.Value = round(stats.SurfaceArea(2),2);
app.VolumeEditField.Value = round(stats.Volume(1),2); 
app.VolumeEditField_2.Value = round(stats.Volume(2),2);
app.SolidityEditField.Value = round(stats.Solidity(1),2);
app.SolidityEditField_2.Value = round(stats.Solidity(2),2);


        end

        % Value changed function: SegmentationthresholdEditField
        function SegmentationthresholdEditFieldValueChanged(app, event)
            value = app.SegmentationthresholdEditField.Value;
            
            app.segmentationThreshold = value;
        end

        % Value changed function: EndingthresholdEditField
        function EndingthresholdEditFieldValueChanged(app, event)
            value = app.EndingthresholdEditField.Value;
            
            app.endingThreshold = value;
        end

        % Value changed function: 
        % AutomaticlyadjuststructuringelementCheckBox
        function AutomaticlyadjuststructuringelementCheckBoxValueChanged(app, event)
            value = app.AutomaticlyadjuststructuringelementCheckBox.Value;
            
            app.automaticlyAdjustStructuringElement = value;
        end

        % Button pushed function: FindsymmetryButton
        function FindsymmetryButtonPushed(app, event)
            I = app.FullImages(:,:,app.currentFileNumber);
            y = 0;
            
            for row = 1:size(I, 1)
                for col = 1:size(I, 2)
                    if I(row, col) > 0
                        y = row;
                        break;
                    end
                end
            end
            Irow = I(y, :);
            [~, col] = find(Irow);
            sym = mean(col);
            app.symmetry = sym;
            
            y = [sym sym];
            x = [1 size(I,1)];
            figure("Name", 'Symmetry')
            imshow(I,[]);
            hold on
            plot(y, x, 'MarkerSize',2, "Color", 'r')
            
            
        end

        % Button pushed function: MakecorrectionsButton
        function MakecorrectionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
    app.MakecorrectionsButton.Enable = 'off';

    % Get szvalue and cvalue
    % ....
    
    % Call dialog box with input values
    app.maskCorrectionApp = maskCorrection(app, app.CroppedImages, app.mask, app.symmetry);
        end

        % Close request function: EyeSegmentationAplication
        function EyeSegmentationAplicationCloseRequest(app, event)
            
            delete(app.maskCorrectionApp)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EyeSegmentationAplication = uifigure('Visible', 'off');
            app.EyeSegmentationAplication.Name = 'Eye Segmentation Aplication';
            app.EyeSegmentationAplication.CloseRequestFcn = createCallbackFcn(app, @EyeSegmentationAplicationCloseRequest, true);
            app.OptionsPanel = uipanel(app.EyeSegmentationAplication);
            app.OptionsPanel.Title = 'Options';
            app.FindsymmetryPanel = uipanel(app.OptionsPanel);
            app.FindsymmetryPanel.Title = 'Find symmetry';
            app.TextArea = uitextarea(app.FindsymmetryPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Find image where nose is exposed, then click button below to find symmetry'};
            app.FindsymmetryButton = uibutton(app.FindsymmetryPanel, 'push');
            app.FindsymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @FindsymmetryButtonPushed, true);
            app.FindsymmetryButton.Text = 'Find symmetry';
            app.SegmentationPanel = uipanel(app.OptionsPanel);
            app.SegmentationPanel.Title = 'Segmentation';
            app.TextArea_2 = uitextarea(app.SegmentationPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Find image where eyes begin, then adjust options below and click start segmentation'};
            app.ChoosestartingpointButton = uibutton(app.SegmentationPanel, 'push');
            app.ChoosestartingpointButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestartingpointButtonPushed, true);
            app.ChoosestartingpointButton.Text = 'Choose starting point';
            app.AutomaticlyadjuststructuringelementCheckBox = uicheckbox(app.SegmentationPanel);
            app.AutomaticlyadjuststructuringelementCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticlyadjuststructuringelementCheckBoxValueChanged, true);
            app.AutomaticlyadjuststructuringelementCheckBox.Text = 'Automaticly adjust structuring element';
            app.AutomaticlyadjuststructuringelementCheckBox.Value = true;
            app.SegmentationthresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.SegmentationthresholdEditFieldLabel.Text = 'Segmentation threshold';
            app.SegmentationthresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.SegmentationthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentationthresholdEditFieldValueChanged, true);
            app.SegmentationthresholdEditField.Value = 0.054;
            app.EndingthresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.EndingthresholdEditFieldLabel.Text = 'Ending threshold';
            app.EndingthresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.EndingthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EndingthresholdEditFieldValueChanged, true);
            app.EndingthresholdEditField.Value = 0.054;
            app.StartsegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.StartsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsegmentationButtonPushed, true);
            app.StartsegmentationButton.Text = 'Start segmentation';
            app.MakecorrectionsButton = uibutton(app.SegmentationPanel, 'push');
            app.MakecorrectionsButton.ButtonPushedFcn = createCallbackFcn(app, @MakecorrectionsButtonPushed, true);
            app.MakecorrectionsButton.Text = 'Make corrections';
            app.DvisualizationandregionpropertiesPanel = uipanel(app.OptionsPanel);
            app.DvisualizationandregionpropertiesPanel.Title = '3D visualization and region properties';
            app.TextArea_3 = uitextarea(app.DvisualizationandregionpropertiesPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Click button below to make 3D visualization of the segmented region and count region properties'};
            app.Make3DvisualizationButton = uibutton(app.DvisualizationandregionpropertiesPanel, 'push');
            app.Make3DvisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @Make3DvisualizationButtonPushed, true);
            app.Make3DvisualizationButton.Text = 'Make 3D visualization';
            app.AreaEditFieldLabel = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.AreaEditField.Editable = 'off';
            app.VolumeEditFieldLabel = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.VolumeEditField.Editable = 'off';
            app.VolumeEditField_2Label = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.VolumeEditField_2Label.Text = 'Volume';
            app.VolumeEditField_2 = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.VolumeEditField_2.Editable = 'off';
            app.AreaEditField_2Label = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.AreaEditField_2Label.Text = 'Area';
            app.AreaEditField_2 = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.AreaEditField_2.Editable = 'off';
            app.LefteyeLabel = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.LefteyeLabel.Text = 'Left eye';
            app.RighteyeLabel = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.RighteyeLabel.Text = 'Right eye';
            app.SolidityEditFieldLabel = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.SolidityEditFieldLabel.Text = 'Solidity';
            app.SolidityEditField = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.SolidityEditField.Editable = 'off';
            app.SolidityEditField_2Label = uilabel(app.DvisualizationandregionpropertiesPanel);
            app.SolidityEditField_2Label.Text = 'Solidity';
            app.SolidityEditField_2 = uieditfield(app.DvisualizationandregionpropertiesPanel, 'numeric');
            app.SolidityEditField_2.Editable = 'off';
            app.ImagesPanel = uipanel(app.EyeSegmentationAplication);
            app.ImagesPanel.Title = 'Images';
            app.UIAxes = uiaxes(app.ImagesPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.NextButton = uibutton(app.ImagesPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ImagesPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.ImagesPanel);
            app.Label.Visible = 'off';
            app.Label.Text = '1/100';
            app.PathPanel = uipanel(app.EyeSegmentationAplication);
            app.PathPanel.Title = 'Path';
            app.LoadimagesButton = uibutton(app.PathPanel, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Text = 'Load images';
            app.Label_2 = uilabel(app.PathPanel);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.EyeSegmentationAplication.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = POM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EyeSegmentationAplication)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EyeSegmentationAplication)
        end
    end
end