classdef maskCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MakecorrectionsUIFigure     matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        PreviousButton              matlab.ui.control.Button
        NextButton                  matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ChoosestartingpointButton   matlab.ui.control.Button
        UpdateButton                matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        DeleteButton                matlab.ui.control.Button
        structuringelementDropDownLabel  matlab.ui.control.Label
        structuringelementDropDown  matlab.ui.control.DropDown
        radiusEditField             matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        thresholdEditFieldLabel     matlab.ui.control.Label
        thresholdEditField          matlab.ui.control.NumericEditField
        radiusLabel                 matlab.ui.control.Label
        numberofelementsusedtoapproximatethediskshapeLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp % main window
        
        images  %cropped images
        mask    %mask matrix
        symmetry
        
        currentImageNo = 1;
        
        positionX
        positionY
        
        maskTemp
        
        threshold = 0.05;
        strel = 'disk'
        r = 1;
        n = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, Images, Masks, Symmetry)
            movegui(app.MakecorrectionsUIFigure,'center');
            app.MainApp = mainApp;
            app.symmetry = Symmetry;
            %Update UI with input values
            app.mask = Masks;
            
            app.images = Images;


%            Masks = imbinarize(Masks);
            
            %display image with overlayed mask
            mask1 = Masks(:,:,1);
            image1 = Images(:,:,1);
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes);
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.MainApp.MakecorrectionsButton.Enable = 'on';
            
            % Call main app's public function
            updateMask(app.MainApp ,app.mask);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: MakecorrectionsUIFigure
        function MakecorrectionsUIFigureCloseRequest(app, event)
            app.MainApp.MakecorrectionsButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.currentImageNo = app.currentImageNo+1;
            if app.currentImageNo == size(app.mask,3)+1
                       
            %display image with overlayed mask
            mask1 = app.mask(:,:,1);
            image1 = app.images(:,:,1);
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes); 
           
            app.currentImageNo = 1; 
            else    
            mask1 = app.mask(:,:,app.currentImageNo);
            image1 = app.images(:,:,app.currentImageNo);
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes); 
            
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.currentImageNo = app.currentImageNo-1;
            if app.currentImageNo == 0
                       
            %display image with overlayed mask
            mask1 = app.mask(:,:,size(app.mask, 3));
            image1 = app.images(:,:,size(app.images, 3));
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes); 
           
            app.currentImageNo = size(app.mask, 3); 
            else    
            mask1 = app.mask(:,:,app.currentImageNo);
            image1 = app.images(:,:,app.currentImageNo);
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes); 
            
            end
        end

        % Button pushed function: ChoosestartingpointButton
        function ChoosestartingpointButtonPushed(app, event)
            f = figure;
            imshow(app.images(:,:,app.currentImageNo),[])
            
            [x,y]=ginput(1);
            hold on
            plot(x,y, 'xg', 'MarkerSize', 10);
            app.positionX = round(x);
            app.positionY = round(y);
            
            pause(2);
            close(f)
            
            %segmentation
           
                I = app.images(:,:, app.currentImageNo);

                    x1 = app.positionX;
                    y1 = app.positionY;
                    y2 = y1;
                    
                    %position of second eye
                    sym = app.symmetry;
                    distance = abs(sym-app.positionX);
                    
                    if x1 > sym
                        x2 = sym - distance;
                    elseif x1 < sym
                        x2 = sym + distance;
                    else
                        warndlg('Point selected incorrectly');
                    end
                    
                    
                    %normalization
                    maxIntensity = max(max(I));
                    I = I./maxIntensity;
                    
                    Isegmented1 = regiongrowing(I, y1, x1, app.threshold); %segmentation, x and y are switched! mistake in function
                    %second eye
                    Isegmented2 = regiongrowing(I, y2, x2, app.threshold);
                    Isegmented = Isegmented1 + Isegmented2; %completed mask
                    
                    Isegmented = imbinarize(Isegmented);
                    
                    if strcmp(app.strel, 'disk') || strcmp(app.strel, 'rectangle')
                        se = strel(app.strel, app.r, app.n)
                        
                        
                    elseif strcmp(app.strel,'diamond') || strcmp(app.strel, 'square')
                        se = strel(app.strel, app.r)
                        
                    end


                    Iclosed = imclose(Isegmented, se);                              %morfological close
                    Iopen = imopen(Iclosed, se);                                    %morfological open
                    %             Isegmented2 = imfill(Isegmented,'holes');
                    
                    app.maskTemp = Iopen;
                    
                    mask1 = app.mask(:,:,app.currentImageNo);
                    image1 = app.images(:,:,app.currentImageNo);
                    image2 = app.images(:,:,app.currentImageNo);
                    
                    figure(1)
                    subplot(2,1,1)                  
                    image1(Iopen ~= 0) = Iopen(Iopen ~= 0);
                    imshow(image1, []);
                    title('New mask')
                    
                    subplot(2,1,2)
                    image2(mask1 ~= 0) = mask1(mask1 ~= 0);
                    imshow(image2, []);
                    title('Previous mask');
                   
        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)
            value = app.thresholdEditField.Value;
            
            app.threshold = value;
        end

        % Value changed function: structuringelementDropDown
        function structuringelementDropDownValueChanged(app, event)
            value = app.structuringelementDropDown.Value;
            
            if strcmp(value, 'disk')
                app.radiusEditField.Enable =true;
                app.radiusLabel.Text = 'radius';
                
                app.EditField_2.Enable = true;
                app.numberofelementsusedtoapproximatethediskshapeLabel.Text = ['number of elements used to' ...
                    '\n approximate the disk shape']
            elseif strcmp(value,'rectangle')
                app.radiusEditField.Enable =true;
                app.radiusLabel.Text = 'm';
                
                app.EditField_2.Enable = true;
                app.numberofelementsusedtoapproximatethediskshapeLabel.Text = 'n'
                
            elseif strcmp(value,'diamond')
                app.radiusEditField.Enable =true;
                app.radiusLabel.Text = 'distance from origin';
                
                app.EditField_2.Enable = false;
                app.numberofelementsusedtoapproximatethediskshapeLabel.Text = ' '
            elseif strcmp(value,'square')
                app.radiusEditField.Enable =true;
                app.radiusLabel.Text = 'width';
                
                app.EditField_2.Enable = false;
                app.numberofelementsusedtoapproximatethediskshapeLabel.Text = ' '
            end
            app.strel = value;
        end

        % Value changed function: radiusEditField
        function radiusEditFieldValueChanged(app, event)
            value = app.radiusEditField.Value;
            app.r = value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.n = value;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.mask(:,:,app.currentImageNo) = app.maskTemp;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            %not working properly
            if app.currentImageNo == size(app.mask,3)
                for i = 1:size(app.mask, 3)-1
                    tmp1(:,:,i) = app.mask(:,:,i);
                end
                tmp3 = tmp1;
            elseif app.currentImageNo == 1
                for i = 2:size(app.mask, 3)
                    tmp1(:,:,i) = app.mask(:,:,i);
                end
                tmp3 = tmp1;
                
            else
                
                for i = 1:app.currentImageNo-1
                    tmp1(:,:,i) = app.mask(:,:,i);
                end
                for i = app.currentImageNo+1:size(app.mask,3)
                    tmp2(:,:,i) = app.mask(:,:,i);
                end
                tmp3 = cat(3, tmp1, tmp2);
            end
            
            app.mask = tmp3;
            
            if app.currentImageNo ~= 1
                app.currentImageNo = app.currentImageNo-1;
            else
                app.currentImageNo = size(app.mask,3);
            end
            
            mask1 = app.mask(:,:,app.currentImageNo);
            image1 = app.images(:,:,app.currentImageNo);
            image1(mask1 ~= 0) = mask1(mask1 ~= 0);
            imshow(image1, [], 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MakecorrectionsUIFigure = uifigure('Visible', 'off');
            app.MakecorrectionsUIFigure.Name = 'Make corrections';
            app.MakecorrectionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MakecorrectionsUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.MakecorrectionsUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.PreviousButton = uibutton(app.MakecorrectionsUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.MakecorrectionsUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Panel = uipanel(app.MakecorrectionsUIFigure);
            app.Panel.Title = 'Panel';
            app.ChoosestartingpointButton = uibutton(app.Panel, 'push');
            app.ChoosestartingpointButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosestartingpointButtonPushed, true);
            app.ChoosestartingpointButton.Text = 'Choose starting point';
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.Panel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.structuringelementDropDownLabel = uilabel(app.Panel);
            app.structuringelementDropDownLabel.Text = 'structuring element';
            app.structuringelementDropDown = uidropdown(app.Panel);
            app.structuringelementDropDown.Items = {'disk', 'rectangle', 'square', 'diamond'};
            app.structuringelementDropDown.ValueChangedFcn = createCallbackFcn(app, @structuringelementDropDownValueChanged, true);
            app.structuringelementDropDown.Value = 'disk';
            app.radiusEditField = uieditfield(app.Panel, 'numeric');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldValueChanged, true);
            app.radiusEditField.Value = 1;
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.thresholdEditFieldLabel = uilabel(app.Panel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.Panel, 'numeric');
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.thresholdEditField.Value = 0.05;
            app.radiusLabel = uilabel(app.Panel);
            app.radiusLabel.Text = 'radius';
            app.numberofelementsusedtoapproximatethediskshapeLabel = uilabel(app.Panel);
            app.numberofelementsusedtoapproximatethediskshapeLabel.Text = {' number of elements used to'; ' approximate the disk shape'};
            % Show the figure after all components are created
            app.MakecorrectionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maskCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MakecorrectionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MakecorrectionsUIFigure)
        end
    end
end